classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FxController                   matlab.ui.container.Panel
        Next                           matlab.ui.control.Button
        Previous                       matlab.ui.control.Button
        Save                           matlab.ui.control.Button
        FunctionName                   matlab.ui.control.TextArea
        NameTextAreaLabel              matlab.ui.control.Label
        Degree                         matlab.ui.control.DropDown
        DegreeofpolynomialDropDownLabel  matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        ImportDataButton               matlab.ui.control.Button
        RefreshpointsButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Prompt                         matlab.ui.control.TextArea
        PromptTextAreaLabel            matlab.ui.control.Label
        Show                           matlab.ui.container.ButtonGroup
        OnlypointsButton               matlab.ui.control.RadioButton
        OnlyaproximationcurveButton    matlab.ui.control.RadioButton
        AproximationcurvePointsButton  matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        function_amount = 1;
        function_database = [f1 f2 f3 f4 f5 f6 f7 f8 f9 f10];
        selected_function = 1; % Description
    end
    
    methods (Access = private)
        
        function ResetPrompt(app)
            set(app.Prompt, 'Value', 'Idle ...')
        end % ResetPrompt function
        
        function ClearAxes(app)
            plot(app.UIAxes, 0, 0);
        end % ClearAxes function
        
        function PrintPrompt(app, msg_type, msg)
        switch msg_type
            case 'I'
                set(app.Prompt, ...
                    'Value', convertStringsToChars(append('INFO: ', msg)), ...
                    'FontColor', '#0000ff');
            case 'W'
                set(app.Prompt, ...
                    'Value', convertStringsToChars(append('WARNING: ', msg)), ...
                    'FontColor', '#ffff00');
            case 'E'
                set(app.Prompt, ...
                    'Value', convertStringsToChars(append('ERROR: ', msg)), ...
                    'FontColor', '#ff0000');
        end % switch  
        end % PrintPrompt function
        
        function show_option = GetShowOption(app)
            show_option = 'CP';
            
            if (get(app.OnlypointsButton, 'Value') == true)
                show_option = 'P';
            elseif (get(app.OnlyaproximationcurveButton, 'Value') == true)
                show_option = 'C';
            elseif (get(app.AproximationcurvePointsButton, 'Value') == true)
                show_option = 'CP';
            else
                PrintPrompt(app, 'E', "Cannot get value from show options.");
                return;
            end % if
            
        end
                
        function degree = GetDegree(app)
            string = get(app.Degree, 'Value');
            switch string
                case '1'
                    degree = 1;
                case '2'
                    degree = 2;
                case '5'
                    degree = 5;
                case 'exp'
                    degree = app.Spinner.Value - 1;
            end % switch
        end % GetDegree function
        
    end % methods
    
    methods (Access = public)
        
        function SaveToDatabase(app,function_structure)
            app.function_database(function_structure.ID) = function_structure;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            point_amount = app.Spinner.Value;               % Current spinner value
            previous_point_amount = event.PreviousValue;    % Spinner value before change

%                 Set table rows name to be numbered
            set(app.UITable, 'RowName', 'numbered');    

%                 Increment
            if (point_amount > previous_point_amount) 
                default_values = {0, 0};
                old_data = get(app.UITable, 'data');    % Save table
                new_data = [old_data;default_values];   % New coordinates = previous coordinates & default coordinates
                set(app.UITable, 'data', new_data);     % Add new coordinates to table
%                 Decrement
            elseif (point_amount < previous_point_amount)
                app.UITable.Data((previous_point_amount), :) = []; 
%                 App error
            else
                PrintPrompt(app, 'E', "Unexpected state of poins amount spinner.")
                return;
            end % if
        end

        % Button pushed function: RefreshpointsButton
        function RefreshpointsButtonPushed(app, event)
            %             Reset prompt
            ResetPrompt(app);
            
%             Reset Axes
            ClearAxes(app);
            
            %             Check piont amount spinner
            if (get(app.Spinner, 'Value') == 0)
                PrintPrompt(app, 'E', "Point amount is set to zero, no data to display.");
                return;
            elseif (get(app.Spinner, 'Value') <= 0)
                PrintPrompt(app, 'E', "Point amount cannot be negative number.");
                return;
            end % if
            
%             Convert table to matrix
            if (class(app.UITable.Data) ~= 'cell')
                PrintPrompt(app, 'E', "Unregognized data type of data structure");
                return;
            else 
                matrix = cell2mat(app.UITable.Data);
            end % if

            polynomial_approximation_degree = GetDegree(app);
            if (~(isnumeric(polynomial_approximation_degree)))
                PrintPrompt(app, 'W', "Polynomial approcimation degree is not numeric, setting default value (1)");
                polynomial_approximation_degree = 1;
            end
            set(app.Prompt, 'Value', append('Degre = ', num2str(polynomial_approximation_degree)));
            
            polynomial_aproximation_coefficients = polyfit(matrix(:,1), matrix(:,2), polynomial_approximation_degree);
            
                                    
%            function = points, function1 = approximation curve
            x = matrix(:,1);
            y = matrix(:,2);
            x1 = min(matrix(:,1)) - 1 : 0.1 : max(matrix(:,1)) + 1;
            y1 = polyval(polynomial_aproximation_coefficients, x1);
            
%             Get show option
            show_option = GetShowOption(app);
            
%             Plot data
            switch show_option
                case 'CP'
                    plot(app.UIAxes, x1, y1, x, y, 'o');
                case 'C'
                    plot(app.UIAxes, x1, y1);
                case 'P'
                    plot(app.UIAxes, x, y, 'o');
            end % switch
        end

        % Selection changed function: Show
        function ShowSelectionChanged(app, event)
            RefreshpointsButtonPushed(app, event);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
%             Select .xlsx file
            [file_name, file_path] = uigetfile({'*.xlsx'}, 'Select a file', 'C:\Documents\');
            if (isequal(file_name, 0))
                PrintPrompt(app, 'I', "User did not selected file, no data imported.")
                return;
            elseif (isequal(file_path, 0))
                PrintPrompt(app, 'E', "Failed getting file path, no data imported.");
                return;
            end % if
            
%             Set 'file' as string where is stored full path name of file
            file = fullfile(file_path, file_name);

%             Read file
            file_data = readtable(file);
            
%             Copy data to app table
            vars = {'X'; 'Y'};
            file_data = file_data(:, vars);
            set(app.UITable, 'Data', file_data);

%             Convert from table to cell array
            table = app.UITable.Data;
            cell_array = table2cell(table);
            set(app.UITable, 'Data', cell_array);

%             Set spinner value according to imported data
            matrix = cell2mat(cell_array);
            set(app.Spinner, 'Value', size(matrix, 1));

%             Send data to ploting function
            RefreshpointsButtonPushed(app,event);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data(:, :) = [];
            set(app.Spinner, 'Value', 0);
            ClearAxes(app);
        end

        % Value changed function: Degree
        function DegreeValueChanged(app, event)
            RefreshpointsButtonPushed(app, event);
        end

        % Button pushed function: Save
        function SavePushed(app, event)
            if (app.Spinner.Value <= 0)
                PrintPrompt(app, 'E', "Function saving failed. Cannot save empty function.");
            end
            function_structure = struct( ...
                'ID', app.selected_function, ...
                'Name', char(app.FunctionName.Value), ...
                'Data', cell2mat(app.UITable.Data), ...
                'Degree', app.Degree.Value);
            if (SaveToDatabase(app, function_structure))
                PrintPrompt(app, 'I', "Function saved succefully.");
            else 
                PrintPrompt(app, 'E', "Function saving failed.");
                return;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {601, 601};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = 'Počet bodů';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Editable = 'off';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RefreshpointsButton = uibutton(app.LeftPanel, 'push');
            app.RefreshpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpointsButtonPushed, true);
            app.RefreshpointsButton.Text = 'Refresh points';
            app.ImportDataButton = uibutton(app.LeftPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DegreeofpolynomialDropDownLabel = uilabel(app.LeftPanel);
            app.DegreeofpolynomialDropDownLabel.Text = 'Degree of polynomial';
            app.Degree = uidropdown(app.LeftPanel);
            app.Degree.Items = {'1', '2', '5', 'exp'};
            app.Degree.ValueChangedFcn = createCallbackFcn(app, @DegreeValueChanged, true);
            app.Degree.Value = '1';
            app.FxController = uipanel(app.LeftPanel);
            app.FxController.Title = 'Function controller';
            app.NameTextAreaLabel = uilabel(app.FxController);
            app.NameTextAreaLabel.Text = 'Name:';
            app.FunctionName = uitextarea(app.FxController);
            app.FunctionName.Placeholder = 'Enter function name';
            app.FunctionName.Value = {'Function 1'};
            app.Save = uibutton(app.FxController, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Text = 'Save function';
            app.Previous = uibutton(app.FxController, 'push');
            app.Previous.Text = 'Previous';
            app.Next = uibutton(app.FxController, 'push');
            app.Next.Text = 'Next';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Show = uibuttongroup(app.RightPanel);
            app.Show.SelectionChangedFcn = createCallbackFcn(app, @ShowSelectionChanged, true);
            app.Show.Title = 'Show';
            app.AproximationcurvePointsButton = uiradiobutton(app.Show);
            app.AproximationcurvePointsButton.Text = 'Aproximation curve + Points';
            app.AproximationcurvePointsButton.Value = true;
            app.OnlyaproximationcurveButton = uiradiobutton(app.Show);
            app.OnlyaproximationcurveButton.Text = 'Only aproximation curve';
            app.OnlypointsButton = uiradiobutton(app.Show);
            app.OnlypointsButton.Text = 'Only points';
            app.PromptTextAreaLabel = uilabel(app.RightPanel);
            app.PromptTextAreaLabel.Text = 'Prompt';
            app.Prompt = uitextarea(app.RightPanel);
            app.Prompt.Editable = 'off';
            app.Prompt.Value = {'Idle ...'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end